classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NLLAAEditFieldLabel            matlab.ui.control.Label
        NLLAAEditField                 matlab.ui.control.EditField
        NITAEditFieldLabel             matlab.ui.control.Label
        NITAEditField                  matlab.ui.control.EditField
        NPLTAEditFieldLabel            matlab.ui.control.Label
        NPLTAEditField                 matlab.ui.control.EditField
        NLLTLEditFieldLabel            matlab.ui.control.Label
        NLLTLEditField                 matlab.ui.control.EditField
        NLLPLLNIEditFieldLabel         matlab.ui.control.Label
        NLLPLLNIEditField              matlab.ui.control.EditField
        ResultsLabel                   matlab.ui.control.Label
        FailureLikelihoodEditFieldLabel  matlab.ui.control.Label
        FailureLikelihoodEditField     matlab.ui.control.EditField
        ExtremePerformanceLikelihoodEditFieldLabel  matlab.ui.control.Label
        ExtremePerformanceLikelihoodEditField  matlab.ui.control.EditField
        HealthyLikelihoodEditFieldLabel  matlab.ui.control.Label
        HealthyLikelihoodEditField     matlab.ui.control.EditField
        BankPerformanceIndicatorLabel  matlab.ui.control.Label
        KohonenNetworkPanel            matlab.ui.container.Panel
        SOMColumnsEditFieldLabel       matlab.ui.control.Label
        SOMColumnsEditField            matlab.ui.control.NumericEditField
        SOMRowsEditFieldLabel          matlab.ui.control.Label
        SOMRowsEditField               matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationsEditField    matlab.ui.control.NumericEditField
        TrainNetworkButton             matlab.ui.control.Button
        ViewSampleHitsButton           matlab.ui.control.Button
        BankmetricsPanel               matlab.ui.container.Panel
        RunExampleButton               matlab.ui.control.Button
        NetIncomeEditFieldLabel        matlab.ui.control.Label
        NetIncomeEditField             matlab.ui.control.NumericEditField
        NonPerformingLoansEditFieldLabel  matlab.ui.control.Label
        NonPerformingLoansEditField    matlab.ui.control.NumericEditField
        TotalAssetsEditFieldLabel      matlab.ui.control.Label
        TotalAssetsEditField           matlab.ui.control.NumericEditField
        TotalLoansEditFieldLabel       matlab.ui.control.Label
        TotalLoansEditField            matlab.ui.control.NumericEditField
        NetLoanLossesEditFieldLabel    matlab.ui.control.Label
        NetLoanLossesEditField         matlab.ui.control.NumericEditField
        ProvisionforLoanLossesEditFieldLabel  matlab.ui.control.Label
        ProvisionforLoanLossesEditField  matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        net
        normFailed
        normExtreme
        normHealthy
        s1
        s2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunExampleButton
        function RunExampleButtonPushed(app, event)
            if isempty(app.net)  
                errordlg('Train Network First');
            else
                nita = app.NetIncomeEditField.Value/(app.TotalAssetsEditField.Value + realmin);
                app.NITAEditField.Value = sprintf("%.2f",nita);
                
                nllaa = app.NetLoanLossesEditField.Value/(app.TotalAssetsEditField.Value - app.TotalLoansEditField.Value + realmin);
                app.NLLAAEditField.Value = sprintf("%.2f",nllaa);
                
                nplta = app.NonPerformingLoansEditField.Value/(app.TotalAssetsEditField.Value + realmin);
                app.NPLTAEditField.Value = sprintf("%.2f",nplta);
                
                nlltl = app.NetLoanLossesEditField.Value/(app.TotalAssetsEditField.Value + realmin);
                app.NLLTLEditField.Value = sprintf("%.2f",nlltl);
                
                nllpllni = (app.NetLoanLossesEditField.Value + app.ProvisionforLoanLossesEditField.Value)/(app.NetIncomeEditField.Value + realmin);
                app.NLLPLLNIEditField.Value = sprintf("%.2f",nllpllni);
                
                [f_percent, e_percent, h_percent] = Allocation(app.net, app.normFailed, app.normExtreme, app.normHealthy, nita,nllaa,nplta,nlltl,nllpllni, app.s1, app.s2);
                
                app.FailureLikelihoodEditField.Value = sprintf("%.2f%%",(f_percent*100));
                app.ExtremePerformanceLikelihoodEditField.Value = sprintf("%.2f%%",(e_percent*100));
                app.HealthyLikelihoodEditField.Value = sprintf("%.2f%%",(h_percent*100));
            end
        end

        % Button pushed function: TrainNetworkButton
        function TrainNetworkButtonPushed(app, event)
             
            app.s2 = app.SOMRowsEditField.Value;
            app.s1 = app.SOMColumnsEditField.Value;
            iter = app.NumberofIterationsEditField.Value;
            
            [app.net, app.normFailed, app.normExtreme, app.normHealthy] = A3(app.s1,app.s2,iter);
            
            app.ViewSampleHitsButton.Enable = true;

        end

        % Button pushed function: ViewSampleHitsButton
        function ViewSampleHitsButtonPushed(app, event)
         
            f1 = figure;
            plotsomhits(app.net, app.normExtreme);
            set(f1, 'name', 'Extreme performance banks sample hits');
            
            f2 = figure;
            plotsomhits(app.net, app.normHealthy);
            set(f2, 'name', 'Healthy banks sample hits');
            
            f3 = figure;
            plotsomhits(app.net, app.normFailed);
            set(f3, 'name', 'Failed banks sample hits');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NLLAAEditFieldLabel = uilabel(app.UIFigure);
            app.NLLAAEditFieldLabel.Text = 'NLLAA';
            app.NLLAAEditField = uieditfield(app.UIFigure, 'text');
            app.NITAEditFieldLabel = uilabel(app.UIFigure);
            app.NITAEditFieldLabel.Text = 'NITA';
            app.NITAEditField = uieditfield(app.UIFigure, 'text');
            app.NPLTAEditFieldLabel = uilabel(app.UIFigure);
            app.NPLTAEditFieldLabel.Text = 'NPLTA';
            app.NPLTAEditField = uieditfield(app.UIFigure, 'text');
            app.NLLTLEditFieldLabel = uilabel(app.UIFigure);
            app.NLLTLEditFieldLabel.Text = 'NLLTL';
            app.NLLTLEditField = uieditfield(app.UIFigure, 'text');
            app.NLLPLLNIEditFieldLabel = uilabel(app.UIFigure);
            app.NLLPLLNIEditFieldLabel.Text = 'NLLPLLNI';
            app.NLLPLLNIEditField = uieditfield(app.UIFigure, 'text');
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.FailureLikelihoodEditFieldLabel = uilabel(app.UIFigure);
            app.FailureLikelihoodEditFieldLabel.Text = 'Failure Likelihood';
            app.FailureLikelihoodEditField = uieditfield(app.UIFigure, 'text');
            app.ExtremePerformanceLikelihoodEditFieldLabel = uilabel(app.UIFigure);
            app.ExtremePerformanceLikelihoodEditFieldLabel.Text = 'Extreme Performance Likelihood';
            app.ExtremePerformanceLikelihoodEditField = uieditfield(app.UIFigure, 'text');
            app.HealthyLikelihoodEditFieldLabel = uilabel(app.UIFigure);
            app.HealthyLikelihoodEditFieldLabel.Text = 'Healthy Likelihood';
            app.HealthyLikelihoodEditField = uieditfield(app.UIFigure, 'text');
            app.BankPerformanceIndicatorLabel = uilabel(app.UIFigure);
            app.BankPerformanceIndicatorLabel.Text = 'Bank Performance Indicator';
            app.KohonenNetworkPanel = uipanel(app.UIFigure);
            app.KohonenNetworkPanel.Title = 'Kohonen Network';
            app.SOMColumnsEditFieldLabel = uilabel(app.KohonenNetworkPanel);
            app.SOMColumnsEditFieldLabel.Text = 'SOM Columns';
            app.SOMColumnsEditField = uieditfield(app.KohonenNetworkPanel, 'numeric');
            app.SOMColumnsEditField.Value = 5;
            app.SOMRowsEditFieldLabel = uilabel(app.KohonenNetworkPanel);
            app.SOMRowsEditFieldLabel.Text = 'SOM Rows';
            app.SOMRowsEditField = uieditfield(app.KohonenNetworkPanel, 'numeric');
            app.SOMRowsEditField.Value = 5;
            app.NumberofIterationsEditFieldLabel = uilabel(app.KohonenNetworkPanel);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.KohonenNetworkPanel, 'numeric');
            app.NumberofIterationsEditField.Value = 10000;
            app.TrainNetworkButton = uibutton(app.KohonenNetworkPanel, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNetworkButtonPushed, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.ViewSampleHitsButton = uibutton(app.KohonenNetworkPanel, 'push');
            app.ViewSampleHitsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSampleHitsButtonPushed, true);
            app.ViewSampleHitsButton.Enable = 'off';
            app.ViewSampleHitsButton.Text = 'View Sample Hits';
            app.BankmetricsPanel = uipanel(app.UIFigure);
            app.BankmetricsPanel.Title = 'Bank metrics';
            app.RunExampleButton = uibutton(app.BankmetricsPanel, 'push');
            app.RunExampleButton.ButtonPushedFcn = createCallbackFcn(app, @RunExampleButtonPushed, true);
            app.RunExampleButton.Text = 'Run Example';
            app.NetIncomeEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.NetIncomeEditFieldLabel.Text = 'Net Income';
            app.NetIncomeEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            app.NetIncomeEditField.Value = 16;
            app.NonPerformingLoansEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.NonPerformingLoansEditFieldLabel.Text = 'Non-Performing Loans';
            app.NonPerformingLoansEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            app.NonPerformingLoansEditField.Value = 10;
            app.TotalAssetsEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.TotalAssetsEditFieldLabel.Text = 'Total Assets';
            app.TotalAssetsEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            app.TotalAssetsEditField.Value = 100;
            app.TotalLoansEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.TotalLoansEditFieldLabel.Text = 'Total Loans';
            app.TotalLoansEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            app.TotalLoansEditField.Value = 110;
            app.NetLoanLossesEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.NetLoanLossesEditFieldLabel.Text = 'Net Loan Losses';
            app.NetLoanLossesEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            app.NetLoanLossesEditField.Value = 9;
            app.ProvisionforLoanLossesEditFieldLabel = uilabel(app.BankmetricsPanel);
            app.ProvisionforLoanLossesEditFieldLabel.Text = 'Provision for Loan Losses';
            app.ProvisionforLoanLossesEditField = uieditfield(app.BankmetricsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end